classdef HB11TEA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        InputsTab                   matlab.ui.container.Tab
        GridLayout2                 matlab.ui.container.GridLayout
        Panel                       matlab.ui.container.Panel
        InputPanelLayout            matlab.ui.container.GridLayout
        AnothervalueEditField       matlab.ui.control.NumericEditField
        AnothervalueEditFieldLabel  matlab.ui.control.Label
        PeriodsInMonthsEditField    matlab.ui.control.NumericEditField
        DummyPeriodsMonthsLabel     matlab.ui.control.Label
        GridLayout3                 matlab.ui.container.GridLayout
        RunModelButton              matlab.ui.control.Button
        OutputsTab                  matlab.ui.container.Tab
        GridLayout                  matlab.ui.container.GridLayout
        OutputPanel                 matlab.ui.container.Panel
        OutputGrid                  matlab.ui.container.GridLayout
        OutputNavTree               matlab.ui.container.Tree
        ProcessNode                 matlab.ui.container.TreeNode
        ElectricityGenerationNode   matlab.ui.container.TreeNode
        EconomicNode                matlab.ui.container.TreeNode
        InvestmentSummaryNode       matlab.ui.container.TreeNode
        CapitalCostsNode            matlab.ui.container.TreeNode
        OperatingCostsNode          matlab.ui.container.TreeNode
        OptionsTab                  matlab.ui.container.Tab
        OptionsLayout               matlab.ui.container.GridLayout
        RestartButton               matlab.ui.control.Button
        RestartsessionLabel         matlab.ui.control.Label
        SaveButton                  matlab.ui.control.Button
        SavethissessionLabel        matlab.ui.control.Label
        OpenButton                  matlab.ui.control.Button
        OpenaprevioussessionLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        AppLayer = ApplicationModule() % Instance of the Application Layer
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: OutputNavTree
        function OutputNavTreeSelectionChanged(app, event)
            
            % Array of selected TreeNodes
            selectedNodes = app.OutputNavTree.SelectedNodes;

            % --- Check if the node is a parent (Has Children) ---
            if ~isempty(selectedNodes.Children)
                return;
            end
            
            % --- Change the Title ---
            selectedEquationName = selectedNodes.Text;
            app.OutputPanel.Title = selectedEquationName;

            % --- Change the Rendered Content Panel ---

            % Clear all selected panels
            set(app.OutputPanel.Children, 'Visible', 'off');
            
            % Conditionally show a child panel
            switch selectedEquationName
                
            end


        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
            % Get File Path
            [filename, path] = uigetfile('*.xml');
            
            % Handle the user cancelling
            if filename == 0
                return;
            end
            
            % Load File into Memory
            app.AppLayer.OpenState(strcat(path, filename));

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            % Select Output Destination
            [filename, path] = uiputfile('*.xml');

            % Save to specified file
            app.AppLayer.SaveState(strcat(path, filename));

        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            
            user_selection = uiconfirm(app.UIFigure,'All unsaved data will be lost.','Confirm Reset');
            
            % Conditionally Clear Application State
            if (strcmp(user_selection, 'OK'))
                app.AppLayer.ResetState();
            end
            

        end

        % Button pushed function: RunModelButton
        function RunModelButtonPushed(app, event)
            
            % --- Load Inputs into the application layer ---

            % Assumption : Children = [ lbl1, val1, lbl2, val2, ... ]

            input_data = struct();
            
            children = app.InputPanelLayout.Children

            % Separate into labels and values
            labels = string([]);
            values = [];

            for i = 1:numel(children)
                
                child_class = class(children(i));

                if strcmp(child_class, "matlab.ui.control.Label")
                    labels = [labels, children(i).Text];
                elseif strcmp(child_class, "matlab.ui.control.NumericEditField")
                    values = [values, children(i).Value];
                end

            end

            % Check that they are the same size
            if length(values) ~= length(labels)
                error("Failed to parse inputs");
            end
            
            % Pack data into a struct
            val_count = length(values);

            for i = 1:val_count
                
                % Remove Spaces from Label and Convert To Lowercase
                strip_key = strrep(labels(i), ' ', '');
                strip_key = lower(strip_key);
                
                % Put into struct with value
                input_data.(strip_key) = values(i);
            end

            % Load into the application layer
            app.AppLayer.LoadInputs(input_data);

            % --- Run the Model ---

            % --- Pull Outputs into the UI Layer ---

            % --- Update Outputs in UI ---

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputsTab.Title = 'Inputs';
            app.GridLayout2 = uigridlayout(app.InputsTab);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.RunModelButton = uibutton(app.GridLayout3, 'push');
            app.RunModelButton.ButtonPushedFcn = createCallbackFcn(app, @RunModelButtonPushed, true);
            app.RunModelButton.Text = 'Run Model';
            app.Panel = uipanel(app.GridLayout2);
            app.InputPanelLayout = uigridlayout(app.Panel);
            app.DummyPeriodsMonthsLabel = uilabel(app.InputPanelLayout);
            app.DummyPeriodsMonthsLabel.Text = 'Periods In Months';
            app.PeriodsInMonthsEditField = uieditfield(app.InputPanelLayout, 'numeric');
            app.AnothervalueEditFieldLabel = uilabel(app.InputPanelLayout);
            app.AnothervalueEditFieldLabel.Text = 'Another value';
            app.AnothervalueEditField = uieditfield(app.InputPanelLayout, 'numeric');
            app.AnothervalueEditField.Value = 5;
            app.OutputsTab = uitab(app.TabGroup);
            app.OutputsTab.Title = 'Outputs';
            app.GridLayout = uigridlayout(app.OutputsTab);
            app.OutputNavTree = uitree(app.GridLayout);
            app.OutputNavTree.SelectionChangedFcn = createCallbackFcn(app, @OutputNavTreeSelectionChanged, true);
            app.ProcessNode = uitreenode(app.OutputNavTree);
            app.ProcessNode.Text = 'Process';
            app.ElectricityGenerationNode = uitreenode(app.ProcessNode);
            app.ElectricityGenerationNode.Text = 'Electricity Generation';
            app.EconomicNode = uitreenode(app.OutputNavTree);
            app.EconomicNode.Text = 'Economic';
            app.InvestmentSummaryNode = uitreenode(app.EconomicNode);
            app.InvestmentSummaryNode.Text = 'Investment Summary';
            app.CapitalCostsNode = uitreenode(app.EconomicNode);
            app.CapitalCostsNode.Text = 'Capital Costs';
            app.OperatingCostsNode = uitreenode(app.EconomicNode);
            app.OperatingCostsNode.Text = 'Operating Costs';
            app.OutputPanel = uipanel(app.GridLayout);
            app.OutputPanel.Title = 'Output';
            app.OutputGrid = uigridlayout(app.OutputPanel);
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.OptionsLayout = uigridlayout(app.OptionsTab);
            app.OpenaprevioussessionLabel = uilabel(app.OptionsLayout);
            app.OpenaprevioussessionLabel.Text = 'Open a previous session';
            app.OpenButton = uibutton(app.OptionsLayout, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SavethissessionLabel = uilabel(app.OptionsLayout);
            app.SavethissessionLabel.Text = 'Save this session';
            app.SaveButton = uibutton(app.OptionsLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RestartsessionLabel = uilabel(app.OptionsLayout);
            app.RestartsessionLabel.Text = 'Restart session';
            app.RestartButton = uibutton(app.OptionsLayout, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HB11TEA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end